classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        L1R1Button           matlab.ui.control.Button
        L2R2Button           matlab.ui.control.Button
        pSliderLabel         matlab.ui.control.Label
        pSlider              matlab.ui.control.Slider
        LinkesBildLabel      matlab.ui.control.Label
        RechtesBildLabel     matlab.ui.control.Label
        VirtuellesBildLabel  matlab.ui.control.Label
        UIAxes3              matlab.ui.control.UIAxes
        LampLabel            matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        ViewSynthesisLabel   matlab.ui.control.Label
        AnleitungLabel       matlab.ui.control.Label
        AnleitungTextArea    matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: L1R1Button
        function L1R1ButtonPushed(app, event)
               app.Lamp.Color = [0,1,0];
               load('Data/rec_im1_1.mat')
               im0 = rec_im1;
               load('Data/rec_im2_1.mat')
               im1 = rec_im2;
               load('Data/spdmap_rl_1.mat')
               disp1 = spdmap_rl;
               load('Data/spdmap_lr_1.mat')
               disp0 = spdmap_lr;
               L1 = imread('L1.JPG');            
               imshow(L1, 'Parent', app.UIAxes)
               R1 = imread('R1.JPG');            
               imshow(R1, 'Parent', app.UIAxes2)             
               smooth_view = free_viewpoint(im0,im1,disp0,disp1,app.pSlider.Value);               
               imshow(smooth_view, 'Parent', app.UIAxes3)
               
        end

        % Button pushed function: L2R2Button
        function L2R2ButtonPushed(app, event)
               L2 = imread('L2.JPG');            
               imshow(L2, 'Parent', app.UIAxes)
               R2 = imread('R2.JPG');            
               imshow(R2, 'Parent', app.UIAxes2)
               app.Lamp.Color = [0,1,0];
               load('Data/rec_im1_2.mat')
               im0 = rec_im1;
               load('Data/rec_im2_2.mat')
               im1 = rec_im2;
               load('Data/spdmap_rl_2.mat')
               disp1 = spdmap_rl;
               load('Data/spdmap_lr_2.mat')
               disp0 = spdmap_lr;                  
               smooth_view = free_viewpoint(im0,im1,disp0,disp1,app.pSlider.Value);               
               imshow(smooth_view, 'Parent', app.UIAxes3)
        end

        % Callback function
        function SliderValueChanged(app, event)
 
        end

        % Value changed function: pSlider
        function pSliderValueChanged2(app, event)
            value = app.pSlider.Value;
            app.Lamp.Color = [1,0,0];
            cla(app.UIAxes3)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.L1R1Button = uibutton(app.UIFigure, 'push');
            app.L1R1Button.ButtonPushedFcn = createCallbackFcn(app, @L1R1ButtonPushed, true);
            app.L1R1Button.Text = 'L1/R1';
            app.L2R2Button = uibutton(app.UIFigure, 'push');
            app.L2R2Button.ButtonPushedFcn = createCallbackFcn(app, @L2R2ButtonPushed, true);
            app.L2R2Button.Text = 'L2/R2';
            app.pSliderLabel = uilabel(app.UIFigure);
            app.pSliderLabel.Text = 'p';
            app.pSlider = uislider(app.UIFigure);
            app.pSlider.Limits = [0 1];
            app.pSlider.ValueChangedFcn = createCallbackFcn(app, @pSliderValueChanged2, true);
            app.LinkesBildLabel = uilabel(app.UIFigure);
            app.LinkesBildLabel.Text = 'Linkes Bild';
            app.RechtesBildLabel = uilabel(app.UIFigure);
            app.RechtesBildLabel.Text = 'Rechtes Bild';
            app.VirtuellesBildLabel = uilabel(app.UIFigure);
            app.VirtuellesBildLabel.Text = 'Virtuelles Bild';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = {'[ ]'};
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = {'[ ]'};
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'[ ]'};
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = {'[ ]'};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.ViewSynthesisLabel = uilabel(app.UIFigure);
            app.ViewSynthesisLabel.Text = 'View Synthesis';
            app.AnleitungLabel = uilabel(app.UIFigure);
            app.AnleitungLabel.Text = 'Anleitung:';
            app.AnleitungTextArea = uitextarea(app.UIFigure);
            app.AnleitungTextArea.Value = {'1. Setzte p auf den gewünschten Wert'; '2. Wähle L1/R1 Button für Bildset 1 oder '; '    L2/R2 Button für Bildset 2'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Rot: Warten...'; 'Grün: Fertig'; 'Wartezeit ca. 3 Minuten'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end